classdef NAE_FlightSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DropDown            matlab.ui.control.DropDown
        Image               matlab.ui.control.Image
        ReturntoMenuButton  matlab.ui.control.Button
        ShowDataButton      matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        LoadDataButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FlightSimNum
        FlightSimText
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'DO NOT PRESS - DATA LOADED';
            app.LoadDataButton.Text = 'Data Loading ...';
            pause(1)
            
            % Read Excel sheet
            [app.FlightSimNum,app.FlightSimText,~] = xlsread('CESDA Data Formated.xlsx');
            
            app.LoadDataButton.Text = 'Data Loaded!'; %Change Button Text
            app.TextArea.Value = {'Press Show Data'};
            app.ShowDataButton.Enable = 'on';
            app.LoadDataButton.Enable = 'off';

        end

        % Button pushed function: ShowDataButton
        function ShowDataButtonPushed(app, event)
            % Shows Image
            app.Image.Visible = 'on';

            % Turns on Drop Down Button
            app.DropDown.Enable = 'on';
  
            % Extract Categories 
            category = app.FlightSimText(1,1:8);

            % Extract x values 
            data = app.FlightSimNum(:,1:8);

            % Extract x values for VR group
            VRData = data(1:2:58-1,:);
            
            % Extract x-values for FSTD group
            FSTD = data(2:2:58,:);
            
            % Find mean for category in VRData
            VRData1 = mean(VRData(:,1));
            VRData2 =  mean(VRData(:,2));
            VRData3 =  mean(VRData(:,3));
            VRData4 = mean(VRData(:,4));
            VRData5 =  mean(VRData(:,5));
            VRData6 = mean(VRData(:,6));
            VRData7 =  mean(VRData(:,7));
            VRData8 = mean(VRData(:,8));
            VRData = [VRData1,VRData2,VRData3,VRData4,VRData5,VRData6,VRData7,VRData8]';
            
            % Find mean for each category in FSTD 
            FSTD1 = mean(FSTD(:,1));
            FSTD2 = mean(FSTD(:,2));
            FSTD3 = mean(FSTD(:,3));
            FSTD4 = mean(FSTD(:,4));
            FSTD5 = mean(FSTD(:,5));
            FSTD6 =mean(FSTD(:,6));
            FSTD7 = mean(FSTD(:,7));
            FSTD8 =mean(FSTD(:,8));
            FSTDData = [FSTD1,FSTD2,FSTD3,FSTD4,FSTD5,FSTD6,FSTD7,FSTD8]';

            % Append data
            DataVal = [VRData,FSTDData];

            % Plot grouped bar graph 
            b = bar(app.UIAxes,DataVal);

            % Set xtick label
            xticklabels(app.UIAxes,category) %Sets X labels to defined labels in Excel Sheet

            % Adding Data Labels on top of Bars
            for i = 1:2
                xtips = b(i).XEndPoints;
                ytips = b(i).YEndPoints;
                labels = string(b(i).YData);
                text(app.UIAxes, xtips, ytips, labels, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')
            end

            % Create Legend
            legend(app.UIAxes,'VR Sim Data', 'FSTD Data')

            %Change Text Box to Info about the graph
            app.TextArea.Value = {['An extensive study conducted ' ...
                'by CESDA looked at the comparison between VR ' ...
                'flight simulators and traditional training devices highlighted ' ...
                'that overall performance, immersiveness, and stress factors ' ...
                'favored the VR simulations, as evidenced by higher scores on the' ...
                ' Likert scale. The data illustrated that VR excelled in delivering ' ...
                'a more immersive and less stressful training experience. Conversely, ' ...
                'the mental demand parameter leaned slightly in favor of the flight ' ...
                'simulation training device, suggesting that it may have certain ' ...
                'advantages in cognitive challenges. In summary, the findings emphasize ' ...
                'that VR simulations outperformed in key aspects, with only mental demand ' ...
                'showing a nuanced preference for traditional training devices.']};

        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_Menu; % Open Menu app
        end

        % Callback function: DropDown, Image
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            if strcmpi('VR Simulator',value)
                app.Image.Visible = 'on'; % Turn on image
                readImage = imread('FlightSim_VR.jpg'); % Read image data
                app.Image.ImageSource = readImage; % Display image

            elseif strcmpi('FSTD',value)
                app.Image.Visible = 'on'; % Turn on image
                readImage = imread('FlightSim_FSTD.jpg'); % Read image data
                app.Image.ImageSource = readImage; % Display image

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'VR vs FSTD Likert Scale Results')
            xlabel(app.UIAxes, 'Results')
            ylabel(app.UIAxes, 'Frequency')
            zlabel(app.UIAxes, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.ShowDataButton = uibutton(app.UIFigure, 'push');
            app.ShowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDataButtonPushed, true);
            app.ShowDataButton.Enable = 'off';
            app.ShowDataButton.Text = 'Show Data';
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FlightSim_VR.jpg');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'VR Simulator', 'FSTD'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'VR Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAE_FlightSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end