classdef NAE_MarineSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReturntoMenuButton            matlab.ui.control.Button
        DataConclusion                matlab.ui.control.TextArea
        SelectandPlotDataButtonGroup  matlab.ui.container.ButtonGroup
        SFSS2Button                   matlab.ui.control.ToggleButton
        RTLXButton                    matlab.ui.control.ToggleButton
        KnowledgeTestButton           matlab.ui.control.ToggleButton
        SelectFigureDropDown          matlab.ui.control.DropDown
        FigureImage                   matlab.ui.control.Image
        UITable                       matlab.ui.control.Table
        FigureDescription             matlab.ui.control.TextArea
        Summary                       matlab.ui.control.TextArea
        MarineSimulationLabel         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectFigureDropDown
        function SelectFigureDropDownValueChanged(app, event)

            value = app.SelectFigureDropDown.Value;

            %%% Perform logic based on value of dropdown

            % If any drop down button was selected other than 'Select Figure'
            if ~strcmpi('Select Figure', value)
                app.SelectFigureDropDown.Items(1) = {'Close Figure'}; % Assign first drop down item as 'Close Figure'
            end

            % If 'Close Figure' drop down item was selected
            if strcmpi('Close Figure', value)
                app.FigureImage.Visible = 'off'; % Turn off image
                app.FigureDescription.Visible = 'off'; % Turn off label
                app.UITable.Visible = 'off'; % Turn off table
                app.SelectFigureDropDown.Items(1) = {'Select Figure'}; % Assign first drop down item as 'Select Figure'

                % If S FSS-2 drop down item was selected
            elseif strcmpi('S FSS-2', value)
                app.FigureImage.Visible = 'off'; % Turn off image
                app.UITable.Visible = 'on'; % Turn on table
                dimensionTable = readcell('SFSS2_Dimensions.xlsx'); % Read Excel data
                dimension = dimensionTable(:, 1); % Assign first column data to dimension
                descriptionOfDimension = dimensionTable(:, 2); % Assign second column data to descriptionOfDimension
                data = [dimension, descriptionOfDimension]; % Append data
                app.UITable.Data = data; % Display data to table
                app.FigureDescription.Visible = 'on'; % Turn on label
                app.FigureDescription.Value = 'Figure 1: Descriptions of S FSS-2 dimensions [2]'; % Add label description

                % If RTLX drop down item was selected
            elseif strcmpi('RTLX', value)
                app.FigureImage.Visible = 'off'; % Turn off image
                app.UITable.Visible = 'on'; % Turn on table
                dimensionTable = readcell('RTLX_Dimensions.xlsx'); % Read Excel data
                dimension = dimensionTable(:, 1); % Assign first column data to dimension
                descriptionOfDimension = dimensionTable(:, 2); % Assign second column data to descriptionOfDimension
                data = [dimension, descriptionOfDimension]; % Append data
                app.UITable.Data = data; % Display data to table
                app.FigureDescription.Visible = 'on'; % Turn on label
                app.FigureDescription.Value = 'Figure 2: Descriptions of RTLX dimensions [2]'; % Add label description

                % If IVR Sim drop down item was selected
            elseif strcmpi('IVR Sim', value)
                app.UITable.Visible = 'off'; % Turn off table
                app.FigureImage.Visible = 'on'; % Turn off image
                readImage = imread('IVRImage.png'); % Read image data
                app.FigureImage.ImageSource = readImage; % Display image
                app.FigureDescription.Visible = 'on'; % Turn on label
                app.FigureDescription.Value = 'Figure 3: IVR headset perspective of simulation [2]'; % Add label description

                % If Desktop Sim drop down item was selected
            elseif strcmpi('Desktop Sim', value)
                app.UITable.Visible = 'off'; % Turn off table
                app.FigureImage.Visible = 'on'; % Turn on image
                readImage = imread('DesktopImage.png'); % Read image data
                app.FigureImage.ImageSource = readImage; % Display image
                app.FigureDescription.Visible = 'on'; % Turn on label
                app.FigureDescription.Value= 'Figure 4: Desktop screen perspective of simulation [2]'; % Add label description
            end
        end

        % Selection changed function: SelectandPlotDataButtonGroup
        function SelectandPlotDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectandPlotDataButtonGroup.SelectedObject;

            %%% Display different data based on the button selected

            % If S FSS-2 Button was selected
            if strcmpi('S FSS-2',selectedButton.Text)
                [marineDataNum,marineDataText,~] = xlsread('MarineSimulaltionData.xlsx'); % Read data
                dimension = marineDataText(2:10,1)'; % Extract dimension categories
                desktop = marineDataNum(1:9,1); % Extract desktop values
                IVR = marineDataNum(1:9,2); % Extract IVR values
                barVal = [desktop,IVR]; % Append desktop and IVR values
                barGraph = bar(app.UIAxes,barVal); % Create grouped bar graph
                xticklabels(app.UIAxes,dimension) % Set xtick label
                ylim(app.UIAxes,[0,6]) % Set y-axis limit
                yticklabels(app.UIAxes,0:1:max(barVal)+1) % Set ytick label
                legend(app.UIAxes,'3D Virtual Reality Desktop', 'Immersive Virtual Reality') % Set legend
                title(app.UIAxes,'Mean S FSS-2 Scale Scores Between Desktop and IVR Group') % Set title
                xlabel(app.UIAxes,'Dimension') % Set x-axis label
                ylabel(app.UIAxes,'Mean Score') % Set y-axes label
                grid(app.UIAxes,'on') % Turn grid on
                disableDefaultInteractivity(app.UIAxes) % Disable user interaction with the plot

                % Desktop bars coordinates
                xPosDesktop = barGraph(:,1).XEndPoints;
                yPosDesktop = barGraph(:,1).YEndPoints;

                % IVR bars oordinates
                xPosIVR = barGraph(:,2).XEndPoints;
                yPosIVR = barGraph(:,2).YEndPoints;

                % Loop data labels
                for i = 1 : numel(dimension)
                    text(app.UIAxes,xPosDesktop(i),yPosDesktop(i)+0.2,sprintf('%0.2f',barVal(i,1)),'HorizontalAlignment','center') % Label Desktop bars
                    text(app.UIAxes,xPosIVR(i),yPosIVR(i)+0.2,sprintf('%0.2f',barVal(i,2)),'HorizontalAlignment','center') % Label IVR bars 
                end

                hold(app.UIAxes,'off') % Hold off plot

                % Conclusion for data in text area
                app.DataConclusion.Value = ['The Short Flow State Scale (S FSS-2) was used ' ...
                    'to analyze the perceived learning ' ...
                    'outcomes of every participant, that is, their ' ...
                    'quality of experience and self-perception of performance. ' ...
                    'The dimensions in S FSS-2 stem from the ' ...
                    'concept of flow state, the cognitive capacity for ' ...
                    'optimal performance. These were determined numerically ' ...
                    'through participants answering questions in the form of a' ...
                    ' likert scale (i.e. 1 is Strongly Disagree, and 5 is Strongly Agree).' ...
                    ' Results revealed that presence of flow in either simulations display no ' ...
                    'significant differences according to Hjellvik [2]. However, the mean differences ' ...
                    'holistically favor the IVR group, which indicates that IVR does improve ' ...
                    'cognitive awareness.'];

                % Enable RTLX button 
                app.RTLXButton.Enable = 'on'; 

                % Disable S FSS-2 button 
                app.SFSS2Button.Enable = 'off'; 

                % If RTLX Button was selected
            elseif strcmpi('RTLX',selectedButton.Text)
                [marineDataNum,marineDataText,~] = xlsread('MarineSimulaltionData.xlsx'); % Read data
                dimension = marineDataText(2:7,6)'; % Extract dimension categories
                desktop = marineDataNum(1:6,6); % Extract desktop values
                IVR = marineDataNum(1:6,7); % Extract IVR values
                barVal = [desktop,IVR]; % Append desktop and IVR values
                barGraph = bar(app.UIAxes,barVal); % Create grouped bar graph
                xticklabels(app.UIAxes,dimension) % Set xtick label
                ylim(app.UIAxes,[0,80]) % Set y-axis limit
                yticklabels(app.UIAxes,0:10:max(barVal)+20) % Set ytick label
                legend(app.UIAxes,'3D Virtual Reality Desktop', 'Immersive Virtual Reality') % Set legend
                title(app.UIAxes,'Mean RTLX Scale Scores Between Desktop and IVR Group') % Set title
                xlabel(app.UIAxes,'Dimension') % Set x-axis label
                ylabel(app.UIAxes,'Mean Score') % Set y-axes label
                grid(app.UIAxes,'on') % Turn grid on
                disableDefaultInteractivity(app.UIAxes) % Disable user interaction with the plot

                % Desktop bars coordinates
                xPosDesktop = barGraph(:,1).XEndPoints;
                yPosDesktop = barGraph(:,1).YEndPoints;

                % IVR bars coordinates
                xPosIVR = barGraph(:,2).XEndPoints;
                yPosIVR = barGraph(:,2).YEndPoints;

                % Loop data labels
                for i = 1 : numel(dimension)
                    text(app.UIAxes,xPosDesktop(i),yPosDesktop(i)+1.8,sprintf('%0.2f',barVal(i,1)),'HorizontalAlignment','center') % Label Desktop bars
                    text(app.UIAxes,xPosIVR(i),yPosIVR(i)+1.8,sprintf('%0.2f',barVal(i,2)),'HorizontalAlignment','center') % Label IVR bars
                end

                hold(app.UIAxes,'off') % Hold off plot

                % Conclusion for data in text area
                app.DataConclusion.Value = ['The NASA-RTLX is a raw and simplified ' ...
                    'version of the questionnaire in NASA-TLX, which is ' ...
                    'used to assess the workload demand through different ' ...
                    'frames/dimensions from a scale of 0-100 (0 being Low and 100 being High) ' ...
                    'of a given task, system, and or performance. ' ...
                    'This is a widely used metric to understand a subject''s ' ...
                    'perception of the task difficulty. A high workload indicates ' ...
                    'problematic information and memory overload, whilst a low workload ' ...
                    'indicates the likelyhood of task disengagement. Results find that there were no ' ...
                    'significant effects between the desktop and the IVR group, ' ...
                    'which can indicate that both environments have high workloads. ' ...
                    'The average of mean scores were 37.33 and 42.77 respectively, ' ...
                    'which is slightly in favor of the desktop group. ' ...
                    'The study concluded that a different instrument may ' ...
                    'be a better assessment to examine the mental workloads ' ...
                    'of the two environments [2]. This poses a contradiction to ' ...
                    'the hypothesis, but not significant enough to disprove it entirely.'];

                % Enable Knowledge Test button 
                app.KnowledgeTestButton.Enable = 'on'; 
                
                % Disable RTLX button 
                app.RTLXButton.Enable = 'off'; 

                % If Knowledge Test Button was selected
            elseif strcmpi('Knowledge Test',selectedButton.Text)
                [marineDataNum,marineDataText,~] = xlsread('MarineSimulaltionData.xlsx'); % Read data
                typeOfTest = marineDataText(2:3,11)'; % Extract test categories
                desktop = marineDataNum(1:2,11); % Extract desktop values
                IVR = marineDataNum(1:2,end); % Extract IVR values
                barVal = [desktop,IVR]; % Append desktop and IVR values
                barGraph = bar(app.UIAxes,barVal); % Create grouped bar graph
                xticklabels(app.UIAxes,typeOfTest) % Set xtick label
                ylim(app.UIAxes,[0,25]) % Set y-axis limit
                yticklabels(app.UIAxes,0:5:max(barVal)+5) % Set ytick label
                legend(app.UIAxes,'3D Virtual Reality Desktop', 'Immersive Virtual Reality') % Set legend
                title(app.UIAxes,'Mean Declarative Knowledge Scores Between Desktop and IVR Group') % Set title
                xlabel(app.UIAxes,'Type of Test') % Set x-axis label
                ylabel(app.UIAxes,'Mean Score') % Set y-axes label
                grid(app.UIAxes,'on') % Turn grid on
                disableDefaultInteractivity(app.UIAxes) % Disable user interaction with the plot

                % Desktop bars coordinates
                xPosDesktop = barGraph(:,1).XEndPoints;
                yPosDesktop = barGraph(:,1).YEndPoints;

                % IVR bars coordinates
                xPosIVR = barGraph(:,2).XEndPoints;
                yPosIVR = barGraph(:,2).YEndPoints;

                % Loop data labels
                for i = 1 : numel(typeOfTest)
                    text(app.UIAxes,xPosDesktop(i),yPosDesktop(i)+0.7,sprintf('%0.2f',barVal(i,1)),'HorizontalAlignment','center') % Label Desktop bars
                    text(app.UIAxes,xPosIVR(i),yPosIVR(i)+0.7,sprintf('%0.2f',barVal(i,2)),'HorizontalAlignment','center') % Label IVR bars
                end

                hold(app.UIAxes,'off') % Hold off plot

                % Conclusion for data in text area
                app.DataConclusion.Value = ['Pre and post knowledge ' ...
                    'tests were used to assess the participants’ ' ...
                    'declarative knowledge (i.e. factual information) ' ...
                    'of the task objectives. This assessment would ' ...
                    'determine which of the two environments display ' ...
                    'greater abstract cognitive improvement. Results reveal ' ...
                    'that the IVR group scored higher in the post test than ' ...
                    'the desktop group as the mean scores were 5.33 and 0.80 ' ...
                    'respectively in the posttest correct scores. The pre-test ' ...
                    'scores had no significant differences (19 and 18.17 respectively), ' ...
                    'which validates the effectiveness of the post test scores as both ' ...
                    'groups had no prerequisite advantages such as IQ. Thus, IVR was proven ' ...
                    'to effectively improve knowledge acquisition among operators. [2] '];

                % Enable S FSS-2 button
                app.SFSS2Button.Enable = 'on';

                % Disable Knowledge Test button
                app.KnowledgeTestButton.Enable = 'off';
            end
        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_Menu; % Open Menu app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = '';
            app.MarineSimulationLabel = uilabel(app.UIFigure);
            app.MarineSimulationLabel.Text = 'Marine Simulation';
            app.Summary = uitextarea(app.UIFigure);
            app.Summary.Editable = 'off';
            app.Summary.Value = {'As modern technologies gain rapid demand within the maritime industry, the level of competence in these dependent operations increase. Educational and training methods require methodical adaptations to meet standard technical competence to mitigate possibilities of error. While costly at the moment, immersive virtual reality poses optimistic probabilities in increasing human competence through simulation. [2]'; ''; 'A study was conducted to explore and compare two technologies: immersive virtual reality (IVR) simulator and 3D virtual reality desktop simulator. Two undergraduate student groups in marine engineering and one group of professional engineer officers were assigned to either of the two technologies and assessed with knowledge tests pre and post simulation. The simulation consisted of starting the fuel oil separator. The purpose of this study was to investigate the technical and cognitive effectiveness of IVR in relation to traditional desktop simulation in the scope of maritime education. [2] '; ''};
            app.FigureDescription = uitextarea(app.UIFigure);
            app.FigureDescription.Editable = 'off';
            app.FigureDescription.Visible = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.FigureImage = uiimage(app.UIFigure);
            app.FigureImage.Visible = 'off';
            app.SelectFigureDropDown = uidropdown(app.UIFigure);
            app.SelectFigureDropDown.Items = {'Select Figure', 'S FSS-2', 'RTLX', 'IVR Sim', 'Desktop Sim'};
            app.SelectFigureDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFigureDropDownValueChanged, true);
            app.SelectFigureDropDown.Value = 'Select Figure';
            app.SelectandPlotDataButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectandPlotDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectandPlotDataButtonGroupSelectionChanged, true);
            app.SelectandPlotDataButtonGroup.Title = 'Select and Plot Data';
            app.KnowledgeTestButton = uitogglebutton(app.SelectandPlotDataButtonGroup);
            app.KnowledgeTestButton.Enable = 'off';
            app.KnowledgeTestButton.Text = 'Knowledge Test';
            app.KnowledgeTestButton.Value = true;
            app.RTLXButton = uitogglebutton(app.SelectandPlotDataButtonGroup);
            app.RTLXButton.Enable = 'off';
            app.RTLXButton.Text = 'RTLX';
            app.SFSS2Button = uitogglebutton(app.SelectandPlotDataButtonGroup);
            app.SFSS2Button.Text = 'S FSS-2';
            app.DataConclusion = uitextarea(app.UIFigure);
            app.DataConclusion.Editable = 'off';
            app.DataConclusion.Value = {'[Select available data to plot and analyze under ''Select and Plot Data''] '};
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAE_MarineSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end