classdef NAE_MedicalSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        Switch                  matlab.ui.control.Switch
        ReturntoMenuButton      matlab.ui.control.Button
        TextArea_2              matlab.ui.control.TextArea
        TextArea                matlab.ui.control.TextArea
        DataShownDropDown       matlab.ui.control.DropDown
        DataShownDropDownLabel  matlab.ui.control.Label
        ShowDataButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        medData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowDataButton
        function ShowDataButtonPushed(app, event)
            % Data Loading
            app.medData = xlsread("MedicalSimData.xlsx", 'A1:D24');

            % Extract relevant data points
            avg = (app.medData([15, 16], 1) / 16 * 100)';
            avg2 = (app.medData([18, 19], 1) / 8 * 100)';

            % Grouped Bar Chart
            avgScores = [avg; avg2];
            b = bar(app.UIAxes, avgScores);
            ylim(app.UIAxes, [0, 100]);

            % Styling the Chart
            legend(app.UIAxes, 'Pre Test', 'Post Test');
            xlabel(app.UIAxes, 'Simulation                                   Control');
            ylabel(app.UIAxes, 'Average Test Scores');
            title(app.UIAxes, {'Average Pre and Post VR Test Scores'; 'For All Students'})
            % Adding Data Labels
            for i = 1:2
                xtips = b(i).XEndPoints;
                ytips = b(i).YEndPoints;
                labels = string(b(i).YData);
                text(app.UIAxes, xtips, ytips, labels, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')
            end
            app.ShowDataButton.Text = 'Data Already Loaded';
            app.ShowDataButton.Enable = 'off';
            app.DataShownDropDown.Enable = 'on';
        end

        % Value changed function: DataShownDropDown
        function DataShownDropDownValueChanged(app, event)
            Type = app.DataShownDropDown.Value;

            if strcmpi('Total Students',Type)

                % Extract relevant data points
                avg = (app.medData([15, 16], 1) / 16 * 100)';
                avg2 = (app.medData([18, 19], 1) / 8 * 100)';

                % Grouped Bar Chart
                avgScores = [avg; avg2];
                b = bar(app.UIAxes, avgScores);
                ylim(app.UIAxes, [0, 100]);

                % Styling the Chart
                legend(app.UIAxes, 'Pre Test', 'Post Test');
                xlabel(app.UIAxes, 'Simulation                                   Control');
                ylabel(app.UIAxes, 'Average Test Scores');
                title(app.UIAxes, {'Average Pre and Post VR Test Scores'; 'For All Students'})

                % Adding Data Labels
                for i = 1:2
                    xtips = b(i).XEndPoints;
                    ytips = b(i).YEndPoints;
                    labels = string(b(i).YData);
                    text(app.UIAxes, xtips, ytips, labels, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')
                end


            elseif strcmpi('Year 1 Students',Type)


                % Extract relevant data points
                avg = (app.medData([15, 16], 2) / 16 * 100)';
                avg2 = (app.medData([18, 19], 2) / 8 * 100)';

                % Grouped Bar Chart
                avgScores = [avg; avg2];
                b = bar(app.UIAxes, avgScores);
                ylim(app.UIAxes, [0, 100]);

                % Styling the Chart
                legend(app.UIAxes, 'Pre Test', 'Post Test');
                xlabel(app.UIAxes, 'Simulation                                   Control');
                ylabel(app.UIAxes, 'Average Test Scores');
                title(app.UIAxes, {'Average Pre and Post VR Test Scores'; 'For Year 1 Students'})
                % Adding Data Labels
                for i = 1:2
                    xtips = b(i).XEndPoints;
                    ytips = b(i).YEndPoints;
                    labels = string(b(i).YData);
                    text(app.UIAxes, xtips, ytips, labels, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')
                end

            elseif strcmpi('Year 2 Students',Type)

                % Extract relevant data points
                avg = (app.medData([15, 16], 3) / 16 * 100)';
                avg2 = (app.medData([18, 19], 3) / 8 * 100)';

                % Grouped Bar Chart
                avgScores = [avg; avg2];
                b = bar(app.UIAxes, avgScores);
                ylim(app.UIAxes, [0, 100]);

                % Styling the Chart
                legend(app.UIAxes, 'Pre Test', 'Post Test');
                xlabel(app.UIAxes, 'Simulation                                   Control');
                ylabel(app.UIAxes, 'Average Test Scores');
                title(app.UIAxes, {'Average Pre and Post VR Test Scores'; 'For Year 2 Students'})

                % Adding Data Labels
                for i = 1:2
                    xtips = b(i).XEndPoints;
                    ytips = b(i).YEndPoints;
                    labels = string(b(i).YData);
                    text(app.UIAxes, xtips, ytips, labels, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom')
                end
            end
        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            close all force; % Close all apps
            NAE_Menu; % Open Menu app
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switchValue = app.Switch.Value;

            if strcmpi('Text',switchValue)
                % Switch is ON, show TextArea and hide Image
                app.TextArea.Visible = 'on';
                app.Image.Visible = 'off';
                app.Image2.Visible = 'off';
            elseif strcmpi('Image',switchValue)
                % Switch is OFF, show Image and hide TextArea
                app.Image.Visible = 'on';
                app.Image2.Visible = 'on';
                app.TextArea.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Average Pre and Post VR Test Scores'; 'For All Students'})
            xlabel(app.UIAxes, 'Simulation                                   Control')
            ylabel(app.UIAxes, 'Average Test Scores')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ShowDataButton = uibutton(app.UIFigure, 'push');
            app.ShowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDataButtonPushed, true);
            app.ShowDataButton.Text = 'Show Data';
            app.DataShownDropDownLabel = uilabel(app.UIFigure);
            app.DataShownDropDownLabel.Enable = 'off';
            app.DataShownDropDownLabel.Text = 'Data Shown';
            app.DataShownDropDown = uidropdown(app.UIFigure);
            app.DataShownDropDown.Items = {'Total students', 'Year 1 Students', 'Year 2 students'};
            app.DataShownDropDown.ValueChangedFcn = createCallbackFcn(app, @DataShownDropDownValueChanged, true);
            app.DataShownDropDown.Enable = 'off';
            app.DataShownDropDown.Value = 'Total students';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'In this study, a cohort comprising first and second-year medical students underwent a comprehensive examination before embarking on an intensive 8-week medical simulation course. Following the completion of the course, participants underwent a subsequent evaluation, revealing a noteworthy enhancement in their performance, evidenced by a substantial 18% increase in overall scores. This discernible progress underscores the positive impact of the immersive medical simulation curriculum on the academic proficiency of the participating students.'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Medical Vr Simulations'};
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Text', 'Image'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Text';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MedicalSimImage.jpeg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'MedicalSimImage2.jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAE_MedicalSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end