classdef NAE_Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EasterEggPanel           matlab.ui.container.Panel
        EasterEggImage           matlab.ui.control.Image
        NumAttemptsIndicator     matlab.ui.control.Label
        CloseButton_2            matlab.ui.control.Button
        AnswerField              matlab.ui.control.EditField
        Equation                 matlab.ui.control.Label
        Question                 matlab.ui.control.Label
        MenuSideBar              matlab.ui.container.Panel
        DONOTCLICKButton         matlab.ui.control.Button
        Switch                   matlab.ui.control.Switch
        SettingsLabel            matlab.ui.control.Label
        CloseButton              matlab.ui.control.Button
        ReferencesButton         matlab.ui.control.Button
        FlightSimulationButton   matlab.ui.control.Button
        SafetySimulationButton   matlab.ui.control.Button
        MedicalSimulationButton  matlab.ui.control.Button
        MarineSimulationButton   matlab.ui.control.Button
        MenuButton               matlab.ui.control.Button
        HomeBackgroundImage      matlab.ui.control.Image
    end

    
    properties (Access = private)
        numAttemptsLeft = 3; % Number of attempts left in answering Easter Egg question
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MarineSimulationButton
        function MarineSimulationButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_MarineSimulation; % Open Marine Simulation app
        end

        % Button pushed function: MedicalSimulationButton
        function MedicalSimulationButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_MedicalSimulation; % Open Medical Simulation app
        end

        % Button pushed function: SafetySimulationButton
        function SafetySimulationButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_SafetySimulation; %Open Safety Simulation app
        end

        % Button pushed function: FlightSimulationButton
        function FlightSimulationButtonPushed(app, event)
           close all force; % Close all apps 
           NAE_FlightSimulation; %Open Flight Simulation app
        end

        % Button pushed function: ReferencesButton
        function ReferencesButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_References; % Open References app
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            app.MenuSideBar.Visible = 'on'; % Set MenuSideBar visibility to 'on' 
            app.MenuButton.Visible = 'off'; % Set Menu Button visibility to 'off' 
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MenuSideBar.Visible = 'off'; % Set MenuSideBar visbility to 'off'
            app.MenuButton.Visible = 'on'; % Set Menu Button visibility to 'on' 
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            Value = app.Switch.Value;

            %%% Switch between 'Light Mode' and 'Dark Mode'

            % If user switched to 'Light Mode'
            if strcmpi('Light Mode', Value)
                imageRead = imread('HomeLightMode.png'); % Read data in image 'HomeLightMode'
                app.HomeBackgroundImage.ImageSource = imageRead; % Assign image with 'HomeLightMode'
                app.Switch.FontColor = '#000000'; % Assign Switch font color black
                app.UIFigure.Color = '#FFFFFF'; % Assign Figure color to white
                app.MenuSideBar.ForegroundColor = '#FFFFFF'; % Assign MenuSideBar foreground color to black
                app.MenuSideBar.BackgroundColor = [0.90,0.90,0.90]; % Assign MenuSideBar background color to light grey
                app.SettingsLabel.FontColor = '#000000'; % Assign Settings label font color to black
                app.SettingsLabel.BackgroundColor = '#FFFFFF'; % Assign Settings label background color to white

                % Change font color of buttons to black
                app.CloseButton.FontColor = '#000000';
                app.MenuButton.FontColor = '#000000';
                app.MarineSimulationButton.FontColor = '#000000';
                app.MedicalSimulationButton.FontColor = '#000000';
                app.SafetySimulationButton.FontColor = '#000000';
                app.FlightSimulationButton.FontColor = '#000000';
                app.ReferencesButton.FontColor = '#000000';
                app.DONOTCLICKButton.FontColor = '#000000';

                % Change background color of buttons to white
                app.CloseButton.BackgroundColor = '#FFFFFF';
                app.MenuButton.BackgroundColor = '#FFFFFF';
                app.MarineSimulationButton.BackgroundColor = '#FFFFFF';
                app.MedicalSimulationButton.BackgroundColor = '#FFFFFF';
                app.SafetySimulationButton.BackgroundColor = '#FFFFFF';
                app.FlightSimulationButton.BackgroundColor = '#FFFFFF';
                app.ReferencesButton.BackgroundColor = '#FFFFFF';
                app.DONOTCLICKButton.BackgroundColor = '#FFFFFF';

                % user switched to Dark Mode'
            elseif strcmpi('Dark Mode', Value)
                imageRead = imread('HomeDarkMode.png'); % Read data in image 'HomeDarkMode'
                app.HomeBackgroundImage.ImageSource = imageRead; % Assign image with 'HomeLightMode'
                app.Switch.FontColor = '#FFFFFF'; % Assign Switch font color to white
                app.UIFigure.Color = '#000000'; % Assign Figure color to black
                app.MenuSideBar.ForegroundColor = '#000000'; % Assign MenuSideBar foreground color to black
                app.MenuSideBar.BackgroundColor = [0.15,0.15,0.15]; % Assign MenuSideBar background color to dark grey
                app.SettingsLabel.FontColor = '#FFFFFF'; % Assign Settings label font color to white
                app.SettingsLabel.BackgroundColor = '#000000'; % Assign Settings label background color to black

                % Change font color of buttons to white
                app.CloseButton.FontColor = '#FFFFFF';
                app.MenuButton.FontColor = '#FFFFFF';
                app.MarineSimulationButton.FontColor = '#FFFFFF';
                app.MedicalSimulationButton.FontColor = '#FFFFFF';
                app.SafetySimulationButton.FontColor = '#FFFFFF';
                app.FlightSimulationButton.FontColor = '#FFFFFF';
                app.ReferencesButton.FontColor = '#FFFFFF';
                app.DONOTCLICKButton.FontColor = '#FFFFFF';

                % Change background color of buttons to black
                app.CloseButton.BackgroundColor = '#000000';
                app.MenuButton.BackgroundColor = '#000000';
                app.MarineSimulationButton.BackgroundColor = '#000000';
                app.MedicalSimulationButton.BackgroundColor = '#000000';
                app.SafetySimulationButton.BackgroundColor = '#000000';
                app.FlightSimulationButton.BackgroundColor = '#000000';
                app.ReferencesButton.BackgroundColor = '#000000';
                app.DONOTCLICKButton.BackgroundColor = '#000000';
            end
        end

        % Button pushed function: DONOTCLICKButton
        function DONOTCLICKButtonPushed(app, event)
            app.EasterEggPanel.Visible = 'on'; % Set EasterEggPanel visibility to 'on'
        end

        % Button pushed function: CloseButton_2
        function CloseButton_2Pushed(app, event)
            app.EasterEggPanel.Visible = 'off'; % Set EasterEggPanel visibility to 'off'
            app.DONOTCLICKButton.Enable = 'off'; % Set DONOTCLICKButton visibility to 'off'
            app.DONOTCLICKButton.Text = 'Locked'; % Assign text value in DONOTCLICKButton
        end

        % Value changed function: AnswerField
        function AnswerFieldValueChanged(app, event)
            value = app.AnswerField.Value;

            % Remove empty spaces in value
            valueWithoutSpaces = strrep(value,' ',''); 

            % Check if user input matches the answer to the question
            if (strcmpi('2ln(abs(x))+c', valueWithoutSpaces) || strcmpi('2lnabsx+c', valueWithoutSpaces) || strcmpi('2ln(absx)+c', valueWithoutSpaces)) && numel(valueWithoutSpaces) > 0

                app.CloseButton_2.Visible = 'off'; % Set CloseButton_2 visibility to 'off'

                % Remove necessary components in EasterEggPanel
                app.Equation.Visible = 'off'; % Set Equation visibility to 'off'
                app.AnswerField.Visible = 'off'; % Set AnswerField visibility to 'off'
                app.Question.Visible = 'off'; % Set Question visibility to 'off'
                app.NumAttemptsIndicator.Visible = 'off'; % Set NumAttemptsIndicator visibility to 'off'

                % Reveal Easter Egg
                app.EasterEggPanel.Title = 'You have found an Easter Egg!'; % Assign title value in EasterEggPanel
                pause(1) % Pause by 1 second
                imageRead = imread('AnimalsImage.png'); % Read data in image ''
                app.EasterEggImage.ImageSource = imageRead; % Assign image with ''

                % Play sound effect
                [ySound,sampleFreq] = audioread('SoundEffect.mp3');
                sound(ySound(1:190000)*1.3, sampleFreq);

                app.EasterEggImage.Visible = 'on'; % Set EasterEggImage visibility to 'on';

                pause(1) % Pause by 1 second
                app.CloseButton_2.Visible = 'on'; % Set CloseButton_2 visibility to 'on'

                % If no value was inputed
            elseif numel(valueWithoutSpaces) <= 0
                app.AnswerField.Value = ''; % Empty AnswerField value
                app.NumAttemptsIndicator.Text = 'Please enter a value'; % Assign text value in NumAttemptsIndicator

                % If user ran out of attempts
            elseif app.numAttemptsLeft == 1
                app.CloseButton_2.Visible = 'off'; % Set CloseButton_2 visibility to 'off'

                % Remove necessary components in EasterEggPanel
                app.Equation.Visible = 'off'; % Set Equation visibility to 'off'
                app.AnswerField.Visible = 'off'; % Set AnswerField visibility to 'off'
                app.Question.Visible = 'off'; % Set Question visibility to 'off'
                app.NumAttemptsIndicator.Visible = 'off'; % Set NumAttemptsIndicator visibility to 'off'

                % Indicate user lost
                app.EasterEggPanel.Title = 'No more attempts. Please try again later.'; % Assign title value in EasterEggPanel
                pause(1) % Pause by 1 second
                app.CloseButton_2.Visible = 'on'; % Set CloseButton_2 visibility to 'on'

            else
                app.numAttemptsLeft = app.numAttemptsLeft - 1; % Decrement number of attempts left by 1
                app.AnswerField.Value = ''; % Empty AnswerField value
                app.NumAttemptsIndicator.Text = sprintf('%0.0f attempt(s) left',app.numAttemptsLeft); % Assign text value in NumAttemptsIndicator
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HomeBackgroundImage = uiimage(app.UIFigure);
            app.HomeBackgroundImage.ImageSource = fullfile(pathToMLAPP, 'HomeDarkMode.png');
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            app.MenuSideBar = uipanel(app.UIFigure);
            app.MenuSideBar.Visible = 'off';
            app.MarineSimulationButton = uibutton(app.MenuSideBar, 'push');
            app.MarineSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @MarineSimulationButtonPushed, true);
            app.MarineSimulationButton.Text = 'Marine Simulation';
            app.MedicalSimulationButton = uibutton(app.MenuSideBar, 'push');
            app.MedicalSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @MedicalSimulationButtonPushed, true);
            app.MedicalSimulationButton.Text = 'Medical Simulation';
            app.SafetySimulationButton = uibutton(app.MenuSideBar, 'push');
            app.SafetySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SafetySimulationButtonPushed, true);
            app.SafetySimulationButton.Text = 'Safety Simulation';
            app.FlightSimulationButton = uibutton(app.MenuSideBar, 'push');
            app.FlightSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @FlightSimulationButtonPushed, true);
            app.FlightSimulationButton.Text = 'Flight Simulation';
            app.ReferencesButton = uibutton(app.MenuSideBar, 'push');
            app.ReferencesButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencesButtonPushed, true);
            app.ReferencesButton.Text = 'References';
            app.CloseButton = uibutton(app.MenuSideBar, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SettingsLabel = uilabel(app.MenuSideBar);
            app.SettingsLabel.Text = 'Settings';
            app.Switch = uiswitch(app.MenuSideBar, 'slider');
            app.Switch.Items = {'Dark Mode', 'Light Mode'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Dark Mode';
            app.DONOTCLICKButton = uibutton(app.MenuSideBar, 'push');
            app.DONOTCLICKButton.ButtonPushedFcn = createCallbackFcn(app, @DONOTCLICKButtonPushed, true);
            app.DONOTCLICKButton.Text = 'DO NOT CLICK';
            app.EasterEggPanel = uipanel(app.UIFigure);
            app.EasterEggPanel.Title = 'Solve the following problem for a reward';
            app.EasterEggPanel.Visible = 'off';
            app.Question = uilabel(app.EasterEggPanel);
            app.Question.Text = 'Find the definite integral ';
            app.Equation = uilabel(app.EasterEggPanel);
            app.Equation.Interpreter = 'latex';
            app.Equation.Text = '2/x';
            app.AnswerField = uieditfield(app.EasterEggPanel, 'text');
            app.AnswerField.ValueChangedFcn = createCallbackFcn(app, @AnswerFieldValueChanged, true);
            app.CloseButton_2 = uibutton(app.EasterEggPanel, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_2Pushed, true);
            app.CloseButton_2.Text = 'Close';
            app.NumAttemptsIndicator = uilabel(app.EasterEggPanel);
            app.NumAttemptsIndicator.Text = 'You have 3 attempts ';
            app.EasterEggImage = uiimage(app.EasterEggPanel);
            app.EasterEggImage.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAE_Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end