classdef NAE_SafetySimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TextArea                   matlab.ui.control.TextArea
        DataSetDropDown            matlab.ui.control.DropDown
        DataSetDropDownLabel       matlab.ui.control.Label
        ReturntoMenuButton         matlab.ui.control.Button
        LogsEditField              matlab.ui.control.EditField
        LogsEditFieldLabel         matlab.ui.control.Label
        DisplaydataButton          matlab.ui.control.Button
        ParticipantsBehaviorScoreCheckBox  matlab.ui.control.CheckBox
        ParticipantsscoreCheckBox  matlab.ui.control.CheckBox
        LoadDataButton             matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data
        Pre_ch_avr_M
        Post_ch_avr_M 
        Pre_Behav_M 
        Post_Behav_M 
        ch_avr_M
        Behav_M
        grade_lvl
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LoadDataButton, LogsEditField
        function LoadDataButtonPushed(app, event)
           
           app.LogsEditField.Value = 'Loading data in:';
           pause(1)
           app.LogsEditField.Value = '3';
           pause(1)
           app.LogsEditField.Value = '2';
           pause(1)
           app.LogsEditField.Value = '1';
           pause(1)
           app.LogsEditField.Value = 'Loading';
           pause(1)
           app.data = xlsread("VR_China_Data_Coding_20181215_final_20181224_SPSS.xlsx","BF1:BI80");
           app.grade_lvl = xlsread("VR_China_Data_Coding_20181215_final_20181224_SPSS.xlsx","F1:F80");
           app.Pre_ch_avr_M = transpose(app.data(2:30,1));
           app.Post_ch_avr_M = transpose(app.data(2:30,2));
           app.Pre_Behav_M = transpose(app.data(2:30,3));
           app.Post_Behav_M  = transpose(app.data(2:30,4));
           app.ch_avr_M = [app.Pre_ch_avr_M;app.Post_ch_avr_M];
           app.Behav_M = [app.Pre_Behav_M;app.Post_Behav_M];
           app.LogsEditField.Value = 'data loading done';
           app.LoadDataButton.Visible = 'off';
           app.DisplaydataButton.Visible = 'on';
        end

        % Value changed function: ParticipantsscoreCheckBox
        function ParticipantsscoreCheckBoxValueChanged(app, event)
            Bar1 = app.ParticipantsscoreCheckBox.Value;
            if Bar1 == 1
                app.UIAxes2.Visible = "on";
            else
                app.UIAxes2.Visible = "off";
            end
            if app.ParticipantsBehaviorScoreCheckBox.Value == 1 &&  app.ParticipantsscoreCheckBox.Value == 1
                app.LoadDataButton.Visible = 'on';
                app.LogsEditField.Value = 'Please Press Load Data';
            elseif (app.ParticipantsBehaviorScoreCheckBox.Value == 0 &&  app.ParticipantsscoreCheckBox.Value == 0) || app.ParticipantsBehaviorScoreCheckBox.Value ~= app.ParticipantsscoreCheckBox.Value 
                app.LoadDataButton.Visible = 'off';
                app.LogsEditField.Value = 'Please Click on Both Checkboxes';
            end
        end

        % Value changed function: ParticipantsBehaviorScoreCheckBox
        function ParticipantsBehaviorScoreCheckBoxValueChanged(app, event)
            Bar2 = app.ParticipantsBehaviorScoreCheckBox.Value;
            if Bar2 == 1
                app.UIAxes.Visible = "on";

            else
                app.UIAxes.Visible = "off";

            end
            if app.ParticipantsBehaviorScoreCheckBox.Value == 1 &&  app.ParticipantsscoreCheckBox.Value == 1
                app.LoadDataButton.Visible = 'on';
                app.LogsEditField.Value = 'Please Press Load Data';
            elseif (app.ParticipantsBehaviorScoreCheckBox.Value == 0 &&  app.ParticipantsscoreCheckBox.Value == 0) || app.ParticipantsBehaviorScoreCheckBox.Value ~= app.ParticipantsscoreCheckBox.Value 
                app.LoadDataButton.Visible = 'off';
                app.LogsEditField.Value = 'Please Click on Both Checkboxes';
            end
        end

        % Callback function: DataSetDropDown, DisplaydataButton, TextArea, 
        % ...and 1 other component
        function DisplaydataButtonPushed(app, event)
            ylim(app.UIAxes,[0 1.5])
            ylim(app.UIAxes2,[0 1.5])
            data_1 = [];
            data_2 = [];
            data_3 = [];
            if strcmpi(app.DataSetDropDown.Value,'None')
                bar(app.UIAxes,0,0)
                bar(app.UIAxes2,0,0)
                app.TextArea.Value = {'...........'};
            elseif strcmpi(app.DataSetDropDown.Value,'School Type M')
                bar(app.UIAxes,transpose(app.Behav_M))
                bar(app.UIAxes2,transpose(app.ch_avr_M))
                app.TextArea.Value = {['This data selected shows the student performace in safety and behavior test when it ' ...
                    'comes to predestrian safety training. This is the score that represent how much did the student score increase with and ' ...
                    'without VR in the school name HLC, China']};
            elseif strcmpi(app.DataSetDropDown.Value,'School Type U')
                bar(app.UIAxes,[app.data(31:50,3),app.data(31:50,4)])
                bar(app.UIAxes2,[app.data(31:50,1),app.data(31:50,2)])
                app.TextArea.Value = {['This data selected shows the student performace in safety and behavior test when it ' ...
                    'comes to predestrian safety training. This is the score that represent how much did the student score increase with and ' ...
                    'without VR in the school name DXH, China']};
            elseif strcmpi(app.DataSetDropDown.Value,'School Type R')
                bar(app.UIAxes,[app.data(51:79,3),app.data(51:79,4)])
                bar(app.UIAxes2,[app.data(51:79,1),app.data(51:79,2)])
                app.TextArea.Value = {['This data selected shows the student performace in safety and behavior test when it ' ...
                    'comes to predestrian safety training. This is the score that represent how much did the student score increase with and ' ...
                    'without VR in the school name FHX, China']};
            elseif strcmpi(app.DataSetDropDown.Value,'Grade 1 All')
                grade_1 = find(app.grade_lvl == 1);
                for i = 1:length(grade_1)
                    data_1 = [data_1; app.data(grade_1(i), :)];
                end
                bar(app.UIAxes,[data_1(:,3),data_1(:,4)])
                bar(app.UIAxes2,[data_1(:,1),data_1(:,2)])
                app.TextArea.Value = {['This data selected shows the student performace in safety and behavior test when it ' ...
                    'comes to predestrian safety training. This is the score that represent how much did the student score increase with and ' ...
                    'without VR in all of the school who is in the 1st grade']};
            elseif strcmpi(app.DataSetDropDown.Value,'Grade 2 All')
                grade_2 = find(app.grade_lvl == 2);
                for i = 1:length(grade_2)
                    data_2 = [data_2; app.data(grade_2(i), :)];
                end
                bar(app.UIAxes,[data_2(:,3),data_2(:,4)])
                bar(app.UIAxes2,[data_2(:,1),data_2(:,2)])
                app.TextArea.Value = {['This data selected shows the student performace in safety and behavior test when it ' ...
                    'comes to predestrian safety training. This is the score that represent how much did the student score increase with and ' ...
                    'without VR in all of the school who is in the 2nd grade']};
            elseif strcmpi(app.DataSetDropDown.Value,'Grade 3 All')
                grade_3 = find(app.grade_lvl == 3);
                for i = 1:length(grade_3)
                    data_3 = [data_3; app.data(grade_3(i), :)];
                end
                bar(app.UIAxes,[data_3(:,3),data_3(:,4)])
                bar(app.UIAxes2,[data_3(:,1),data_3(:,2)])
                app.TextArea.Value = {['This data selected shows the student performace in safety and behavior test when it ' ...
                    'comes to predestrian safety training. This is the score that represent how much did the student score increase with and ' ...
                    'without VR in all of the school who is in the 3rd grade']};
            end
            legend(app.UIAxes,'Without Vr','With Vr')
            legend(app.UIAxes2,'Without Vr','With Vr')
            app.DataSetDropDownLabel.Visible = 'on';
            app.DataSetDropDown.Visible = 'on';
            app.TextArea.Visible = 'on';
            app.DisplaydataButton.Visible = 'off';
        end

        % Button pushed function: ReturntoMenuButton
        function ReturntoMenuButtonPushed(app, event)
            close all force; % Close all apps 
            NAE_Menu; % Open Menu app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Participants Behavior score')
            xlabel(app.UIAxes, 'Participant')
            ylabel(app.UIAxes, 'Score')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @DisplaydataButtonPushed, true);
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Participants Safety Score')
            xlabel(app.UIAxes2, 'Participants')
            ylabel(app.UIAxes2, 'Score')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Visible = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.ParticipantsscoreCheckBox = uicheckbox(app.UIFigure);
            app.ParticipantsscoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantsscoreCheckBoxValueChanged, true);
            app.ParticipantsscoreCheckBox.Text = 'Participants score';
            app.ParticipantsBehaviorScoreCheckBox = uicheckbox(app.UIFigure);
            app.ParticipantsBehaviorScoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParticipantsBehaviorScoreCheckBoxValueChanged, true);
            app.ParticipantsBehaviorScoreCheckBox.Text = 'Participants Behavior Score';
            app.DisplaydataButton = uibutton(app.UIFigure, 'push');
            app.DisplaydataButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaydataButtonPushed, true);
            app.DisplaydataButton.Visible = 'off';
            app.DisplaydataButton.Text = 'Display data';
            app.LogsEditFieldLabel = uilabel(app.UIFigure);
            app.LogsEditFieldLabel.Text = 'Logs';
            app.LogsEditField = uieditfield(app.UIFigure, 'text');
            app.LogsEditField.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LogsEditField.Value = 'Please Click on Both Checkboxes';
            app.ReturntoMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMenuButtonPushed, true);
            app.ReturntoMenuButton.Text = 'Return to Menu';
            app.DataSetDropDownLabel = uilabel(app.UIFigure);
            app.DataSetDropDownLabel.Visible = 'off';
            app.DataSetDropDownLabel.Text = 'Data Set';
            app.DataSetDropDown = uidropdown(app.UIFigure);
            app.DataSetDropDown.Items = {'None', 'School Type M', 'School Type U', 'School Type R', 'Grade 1 All', 'Grade 2 All', 'Grade 3 All'};
            app.DataSetDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydataButtonPushed, true);
            app.DataSetDropDown.Visible = 'off';
            app.DataSetDropDown.Value = 'None';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @DisplaydataButtonPushed, true);
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'...........'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAE_SafetySimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end